classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Image           matlab.ui.control.Image
        t_cSlider       matlab.ui.control.Slider
        t_cSliderLabel  matlab.ui.control.Label
        Label_4         matlab.ui.control.Label
        Lamp            matlab.ui.control.Lamp
        Label_3         matlab.ui.control.Label
        TextArea_2      matlab.ui.control.TextArea
        Label_2         matlab.ui.control.Label
        TextArea        matlab.ui.control.TextArea
        EditField       matlab.ui.control.NumericEditField
        Label           matlab.ui.control.Label
        Button_2        matlab.ui.control.Button
        Button          matlab.ui.control.Button
    end


    properties (Access = private)
        v=200 % velocity
        t_color=0.9
        stop_flag=0
        run_des=[]
    end

    properties (Access = public)
        img=zeros(20,20) % output img
    end

    methods (Access = private)
        %% 运行函数
        function sendcomm_run(app,port,v,dv)
            % 增加限幅
            if dv>1000
                dv=1000;
            elseif dv<-1000
                dv=-1000;
            end
            vl=v+dv+32768;
            vr=v-dv;
            % 前进为左边反转，右边正转
            vlhex=dec2hex(vl,4);
            vrhex=dec2hex(vr,4);
            vlh= vlhex(1:2) ;%高位
            vll=vlhex(3:4);%低位
            vrh= vrhex(1:2) ;%高位
            vrl=vrhex(3:4) ;%低位

            sendbuff=zeros(1,9);
            % 16进制 55 aa 71 04 10 vlh vll vrh vrl sum
            sendbuff(1)= 85;
            sendbuff(2)= 170;
            sendbuff(3)= 113;
            sendbuff(4)= 4;
            sendbuff(5)= 16;
            sendbuff(6)= hex2dec(vlh);
            sendbuff(7)= hex2dec(vll);
            sendbuff(8)= hex2dec(vrh);
            sendbuff(9)= hex2dec(vrl);
            %校验和
            %校验位=前面所有数据之和，取最后两位
            add=sum(sendbuff,[1 2 3 4 5 6 7 8 9]);
            two_bits_d=rem(add,256);%10进制下对256取余，在16进制下为2位
            % two_bits_h= dec2hex(two_bits_d);% 发送数据以10进制存储，因此不需转换
            sendbuff(10)= two_bits_d;
            write(port,sendbuff,"uint8");
        end

        %% 停止函数
        function sendcomm_stop(app,port)
            sendbuff=zeros(1,9);
            sendbuff(1)= hex2dec('55');
            sendbuff(2)= hex2dec('aa');
            sendbuff(3)= hex2dec('71');
            sendbuff(4)= hex2dec('04');
            sendbuff(5)= hex2dec('10');
            sendbuff(6)= hex2dec('00');
            sendbuff(7)= hex2dec('00');
            sendbuff(8)= hex2dec('00');
            sendbuff(9)= hex2dec('00');
            sendbuff(10)= hex2dec('84');
            write(port,sendbuff,"uint8")
        end

        %% 旋转函数
        function sendcomm_spin(app,port,dir,time)
            temp_v=200;
            vl=temp_v+32768*(dir-1);
            vr=temp_v+32768*(dir-1);
            vlhex=dec2hex(vl,4);
            vrhex=dec2hex(vr,4);
            vlg= vlhex(1:2) ;%高位
            vld=vlhex(3:4);%低位
            vrg= vrhex(1:2) ;%高位
            vrd=vrhex(3:4) ;%低位

            sendbuff=zeros(1,9);
            sendbuff(1)= hex2dec('55');
            sendbuff(2)= hex2dec('aa');
            sendbuff(3)= hex2dec('71');
            sendbuff(4)= hex2dec('04');
            sendbuff(5)= hex2dec('10');
            sendbuff(6)= hex2dec(vlg);
            sendbuff(7)= hex2dec(vld);
            sendbuff(8)= hex2dec(vrg);
            sendbuff(9)= hex2dec(vrd);
            %校验和
            %校验位=前面所有数据之和，取最后两位
            add=sum(sendbuff,[1 2 3 4 5 6 7 8 9]);
            two_bits_d=rem(add,256);%10进制下对256取余，在16进制下为2位
            sendbuff(10)= two_bits_d;
            write(port,sendbuff,"uint8");

            pause(time/10);

            % 停止机器人
            sendcomm_stop(app,port);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 运行按钮
            app.Lamp.Color="green";
            addpath('C:\Users\YawnFun\Documents\project\Kin2\Mex');
            % Create Kinect 2 object and initiqalize it
            k2 = Kin2('color','depth');

            % 接收读码器信号的端口
            clear obj1
            obj1=serialport("COM3",9600);
            u_QR_Serial(obj1);

            % 发送控制指令的端口
            clear obj2
            obj2=serialport("COM7",19200,'Timeout', 0.2);

            % 运行控制
            run_flag=0;
            run_pos=[];
%             app.run_des=[];
            % v=200;

            % 节点大小与确认裁剪的尺寸问题
            nodesize=9;
            height_cd=375;
            width_cd=512;
            if rem(height_cd,nodesize)% 如果不能整除，则进一步裁剪
                height_cd=nodesize*floor(height_cd/nodesize);
            end
            if rem(width_cd,nodesize)
                width_cd = nodesize*floor(width_cd/nodesize)-1;
            end
            % out = zeros(height_cd,width_cd,3,'uint8');
            % figure, h1 = imshow(out,[]);
            % imshow(out,)
            % 存贮边缘
            edges_last_time=[(height_cd-10*nodesize:nodesize:height_cd)',ones(11,1),width_cd*ones(11,1)];

            % 膨胀和腐蚀的卷积核
            core=strel('disk',7);

            % PID控制器
            dir_last_time=0;% 滤波后的，上一次循环的，机器人方向
            dir2_last_time=0;% 滤波前的，上一次循环的，预期的下一次，机器人方向
            weigh_last_time=0;
            kp=6.04;
            ki=0;
            kd=4.92;
            integrator=0;

            % 语音提示
            [audio_stop,Fs]=audioread('Resource\blocked_by_barrier.mp3');


            % 在没有运行指令时停止等待
            if isempty(app.run_des)
                app.TextArea.Value = "Waiting for QR code input!";
                while(size(obj1.UserData,2)~=6) %没有收到指令
                    pause(0.1);%等待
                end
            else
                run_flag=1;
                app.TextArea.Value = "Set destination as:"+num2str(app.run_des);
            end

            while(~app.stop_flag)
%                 tic
                % 检查命令
                if(size(obj1.UserData,2)==6)
                    data=obj1.UserData;
                    data_s=char(data);
                    data_n=str2double(data_s);
                    comm=floor(data_n/10000);%命令码
                    num=rem(data_n,10000);%命令数
                    switch comm
                        case 0 % 停止
                            sendcomm_stop(app,obj2);
                            % fprintf("Stopped by manual input!\n");
                            app.TextArea.Value = "Stopped by QR input!";
                            run_flag=0;
                        case 1 % 左转
                            sendcomm_spin(app,obj2,comm,num);
                        case 2 % 右转
                            sendcomm_spin(app,obj2,comm,num);
                        case 3 % 到达
                            run_pos=num;
                            %fprintf("Now arrived at: %d\n",run_pos);
                            app.TextArea.Value = "Now arrived at:"+num2str(run_pos);
                        case 4 % 出发
                            app.run_des=num;
                            %fprintf("Set destination as: %d\n",run_des);
                            app.TextArea.Value = "Set destination as:"+num2str(num);
                            app.TextArea_2.Value = num2str(num);
%                             app.EditField_2.Value=num;
                            run_flag=1;
                        case 5 % 调速
                            app.v=num;
                            %fprintf("New velocity is: %d\n",app.v);
                            app.EditField.Value=num;
                            app.TextArea.Value = "New velocity is: "+num2str(num);
                        otherwise
                            %fprintf("Wrong Command!\n");
                            app.TextArea.Value = "Wrong Command!";
                    end
                    obj1.UserData=[];
                elseif size(obj1.UserData,2)>6
                    %fprintf("Wrong Communication!\n");
                    app.TextArea.Value = "Wrong Communication!";
                    obj1.UserData=[];
                end

                %根据命令运行
                if run_flag % 正在运行
                    if run_pos==app.run_des % 到达目的地
                        fprintf("Reached destination!\n");
                        sendcomm_stop(app,obj2);
                        run_flag=0;
                    else % 没有达到
                        % 读取新的图像
                        validData = k2.updateData;
                        if validData

                            % Copy data to Matlab matrices
                            depth = k2.getDepth;
                            color = k2.getColor;
                            %         [depth,~]=undistortImage(depth,d_int);
                            %         [color,~]=undistortImage(color,c_int);
                            color=imresize(color,[375,667]);

                            depthColor_c=fliplr(imcrop(color,[89 1 width_cd height_cd-1]));
                            depthColor_c=u_basic_process(depthColor_c,3,core);
                            depthColor_d=fliplr(imcrop(depth,[1 8 width_cd height_cd-1]));
                            depthColor_d(depthColor_d>4096) = 4096;
                            %                 depthColor_d=depthColor_d.*16;
                            depthColor_d=u_basic_process(depthColor_d,3,core);
                            %imshowpair(depthColor_d,depthColor_c);


                            % 查找道路标线

                            [edges,barrier,barrier_pos]=u_plane_regiongrowing(depthColor_c,depthColor_d,nodesize,core,app.t_color);
                            edges=(edges+edges_last_time)/2;% FIR滤波器平滑edges
                            if isempty(barrier)% 没有障碍物
                                [app.img,dir1,dir2,weigh]=u_APF(depthColor_c,edges,app.v);
                            elseif barrier_pos(1)<width_cd/2 && barrier_pos(2)>width_cd/2 % 停车
                                sendcomm_stop(app,obj2);
                                sound(audio_stop,Fs);
                                pause(1.5);
                                continue;
                            else %避障
                                edges_and_barrier=union(edges,barrier,"rows");
                                [app.img,dir1,dir2,weigh]=u_APF(depthColor_c,edges_and_barrier,app.v);
                            end
                            edges_last_time=edges;% 更新上一次的边界值
                            
                            app.Image.ImageSource=app.img;drawnow;
                            % imshow(app.img,'Parent',app.UIAxes);drawnow;
%                             time = toc;
%                             time = num2str(time);
%                             app.TextArea.Value =time;


                            %控制器
                            new_weigh=weigh/(weigh+weigh_last_time);
                            new_weigh_last_time=weigh_last_time/(weigh+weigh_last_time);
                            dir_this_time=dir1*new_weigh+dir2_last_time*new_weigh_last_time;
                            %                 t=t+toc(time);
                            %                 plot(t, dir_this_time, '-o', 'MarkerFaceColor', 'b');  % Update the plot
                            %                 drawnow;  % Force the plot to update

                            integrator=integrator+dir_this_time;
                            if integrator>app.v
                                integrator=app.v;
                            elseif integrator<-app.v
                                integrator=-app.v;
                            end
                            dv=fix(kp*dir_this_time+ki*integrator+kd*(dir_this_time-dir_last_time));
                            %                 dv=fix(dir_this_time);
                            dir_last_time=dir1;
                            dir2_last_time=dir2;
                            weigh_last_time=weigh;

                            %                 dv=fix(-dir*100);
                            sendcomm_run(app,obj2,app.v,dv)
                            %                 disp(dir_this_time);
                        end
                    end
                else % 停止运行
                    pause(0.1);
                end
            end
            sendcomm_stop(app,obj2);
            k2.delete;
            clear;
            %close all
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.v = app.EditField.Value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.stop_flag=1;
            app.Lamp.Color='red';
            app.TextArea.Value = "Stopped by keybord input!";
        end

        % Value changed function: t_cSlider
        function t_cSliderValueChanged(app, event)
            value = app.t_cSlider.Value;
            app.t_color=value;
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            value = cell2mat(value);
            value = str2double(value);
            if value>=0
                app.run_des=value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '启动';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '停止';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '运行速度';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [0 2048];
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 200;
            app.TextArea = uitextarea(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '当前目标';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '运行状态';
            app.Lamp = uilamp(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '提示信息';
            app.t_cSliderLabel = uilabel(app.UIFigure);
            app.t_cSliderLabel.Text = 't_c';
            app.t_cSlider = uislider(app.UIFigure);
            app.t_cSlider.Limits = [0 1];
            app.t_cSlider.ValueChangedFcn = createCallbackFcn(app, @t_cSliderValueChanged, true);
            app.t_cSlider.Value = 0.9;
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end